classdef appbci2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UITable                matlab.ui.control.Table
        Panel                  matlab.ui.container.Panel
        Lamp                   matlab.ui.control.Lamp
        EditField              matlab.ui.control.EditField
        EXITButton             matlab.ui.control.Button
        channelchoosePanel     matlab.ui.container.Panel
        Channel6CheckBox       matlab.ui.control.CheckBox
        Channel5CheckBox       matlab.ui.control.CheckBox
        Channel4CheckBox       matlab.ui.control.CheckBox
        Channel3CheckBox       matlab.ui.control.CheckBox
        Channel2CheckBox       matlab.ui.control.CheckBox
        Channel1CheckBox       matlab.ui.control.CheckBox
        frequencyhigh          matlab.ui.control.NumericEditField
        fhLabel                matlab.ui.control.Label
        frequencylow           matlab.ui.control.NumericEditField
        flEditFieldLabel       matlab.ui.control.Label
        predict_EditField      matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        STOPButton             matlab.ui.control.Button
        COMEditField           matlab.ui.control.NumericEditField
        COMEditFieldLabel      matlab.ui.control.Label
        SamplingTimeEditField  matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        StartButton            matlab.ui.control.Button
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SamplingTime; % Description
        frequency;
        noisy_data % Description
        board_shim % Description
        preset;
    end

    methods (Access = public)
        %%%%%%%%%%%%%%% CCA %%%%%%%%%%%%%%%
        %         function max_id = app.find_SSVEP_frequency(data)
        %
        %             % 获取刺激频率的数量
        %
        %             t = (0:1/250:(size(data, 2)-1)/250)';
        %
        %             rous = [];
        %
        %             EEG_data = data;
        %
        %             for fr = [6.6, 7.5, 8.57, 10]
        %                 reference_signals = [];
        %                 for n = 1:2
        %                     reference_signals = [reference_signals;sin(2*pi*fr*n*t)';cos(2*pi*fr*n*t)'];
        %                 end
        %                 % 执行CCA分析
        %                 [~, ~, cca_r] = canoncorr(EEG_data', reference_signals');
        %                 rous = [rous, cca_r(1)];
        %
        %             end
        %             [~, max_id] = max(rous);
        %             disp(max_id)
        %
        %
        %         end
        %         %%%%%%%%%%%%%%% END %%%%%%%%%%%%%%%
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %BoardShim.set_log_file('brainflow.log');
            %BoardShim.enable_dev_board_logger();
            app.Lamp.Color = [1.00,0.00,0.00];
            params = BrainFlowInputParams();
            params.serial_port = 'COM11';

            %board_shim = BoardShim(int32(BoardIds.SYNTHETIC_BOARD), params);
            app.board_shim = BoardShim(int32(BoardIds.CYTON_BOARD), params);
            app.frequency = app.freq.Value;
            app.preset = int32(BrainFlowPresets.DEFAULT_PRESET);
            app.board_shim.prepare_session();
            app.board_shim.add_streamer('file://data_default.csv:w', app.preset);
            i=0;

            %%%%%%%%%%%%%%% 连接小车 %%%%%%%%%%%%%%%
            device = bluetooth('YUANSHEN');
            configureTerminator(device,'CR/LF');
            disp("开始")
            %%%%%%%%%%%%%%% END %%%%%%%%%%%%%%%
            counter = 0;
            while(i<10)
                app.board_shim.start_stream(45000, '');  %45000是buffersize
                pause(5);
                %disp(i);
                app.board_shim.stop_stream();
                data = app.board_shim.get_current_board_data(app.SamplingTimeEditField.Value*1000, app.preset);%5000是指读了最后多少点
                size(data);

                i=i+1;

                filename = char(strcat('S', num2str(app.frequency), 'T', num2str(i), '.mat'));
                % disp(filename);
                % save(filename, 'data');

                %%%%%%%%%%%%%%% 数据预处理 %%%%%%%%%%%%%%%
                eeg_channels = BoardShim.get_eeg_channels(int32(BoardIds.SYNTHETIC_BOARD), app.preset);
                [channel,time] = size(data);
                eeg_afterMean = zeros(size(data));

                for j = 1:channel
                    eeg_afterMean(j,:) = data(j,:)-mean(data(j,:),2);
                end


                NN=2;
                fl = app.frequencylow.Value;
                fh = app.frequencyhigh.Value;
                fs = 250;

                [b, a] = butter(NN, [fl,fh]/fs*2);
                eeg_afterFiltFilt = zeros(size(eeg_afterMean));

                for j = 1:channel
                    eeg_afterFiltFilt(j,:) = filtfilt(b,a,squeeze(eeg_afterMean(j,:)));
                end
                [m, n] = butter(NN, [49,51]/fs*2,"stop");
                eeg_afterNotchFilter =zeros(size(eeg_afterFiltFilt));
                for j = 1:channel
                    eeg_afterNotchFilter(j,:) = filter(m,n,squeeze(eeg_afterFiltFilt(j,:)));
                end
                %%%%%%%%%%%%%%% END %%%%%%%%%%%%%%%
                % apply wavelet denoising to the first eeg channel %
                % 初始化一个空数组来存储选择的通道 
                selected_eeg_channels = []; 
                % 获取各个复选框的状态 
                if app.Channel1CheckBox.Value 
                    selected_eeg_channels = [selected_eeg_channels; eeg_channels(3)]; 
                end 
                if app.Channel2CheckBox.Value 
                    selected_eeg_channels = [selected_eeg_channels;  eeg_channels(4)]; 
                end 
                if app.Channel3CheckBox.Value 
                    selected_eeg_channels = [selected_eeg_channels;  eeg_channels(5)];
                end 
                if app.Channel4CheckBox.Value 
                    selected_eeg_channels = [selected_eeg_channels;  eeg_channels(6)]; 
                end 
                if app.Channel5CheckBox.Value 
                    selected_eeg_channels = [selected_eeg_channels;  eeg_channels(7)]; 
                end
                if app.Channel6CheckBox.Value
                    selected_eeg_channels = [selected_eeg_channels;  eeg_channels(8)]; 
                end % 输出选中的通道号，供调试使用 
               % disp('选中的通道数据:'); 
               % disp(size(selected_eeg_channels))
                %first_eeg_channel = eeg_channels(3:8);
                %app.noisy_data =  eeg_afterNotchFilter(first_eeg_channel, :);
                app.noisy_data =  eeg_afterNotchFilter(selected_eeg_channels, :);
                size(app.noisy_data)
                fft_signal = abs(fft(app.noisy_data));
                %denoised_data = DataFilter.perform_wavelet_denoising(noisy_data, int32(WaveletTypes.DB3), 3, int32(WaveletDenoisingTypes.SURESHRINK), int32(ThresholdTypes.HARD), int32(WaveletExtensionTypes.SYMMETRIC), int32(NoiseEstimationLevelTypes.FIRST_LEVEL));
                X = 1:length(app.noisy_data);
                X_FFT = (1:length(app.noisy_data))*250/time;
                plot(app.UIAxes,X,app.noisy_data');
                plot(app.UIAxes_2,X_FFT,fft_signal);
                prediected = find_SSVEP_frequency(app.noisy_data);
                app.predict_EditField.Value = prediected;
                counter = counter+1;
                switch(prediected)
                    case 1 % 前进
                        updateTable(app, counter, prediected, "前进", 9.25)
                        num = ['5A';'A5';'04';'01';'00';'05';'AA'];  %前进
                        num = hex2dec(num);
                        write(device,num,'string')
                        pause(1)
                        num = ['5A';'A5';'04';'00';'00';'04';'AA'];  %停
                        num = hex2dec(num);
                        write(device,num,'string')
                    case 2
                        updateTable(app, counter, prediected, "右移", 9.25)
                        num = ['5A';'A5';'04';'00';'02';'06';'AA'];  %右
                        num = hex2dec(num);
                        write(device,num,'string')
                        pause(1)
                        num = ['5A';'A5';'04';'00';'00';'04';'AA'];  %停
                        num = hex2dec(num);
                        write(device,num,'string')
                    case 3
                        updateTable(app, counter, prediected, "后移", 9.25)
                        num = ['5A';'A5';'04';'02';'00';'06';'AA'];  %下
                        num = hex2dec(num);
                        write(device,num,'string')
                        pause(1)
                        num = ['5A';'A5';'04';'00';'00';'04';'AA'];  %停
                        num = hex2dec(num);
                        write(device,num,'string')
                    case 4
                        updateTable(app, counter, prediected, "左移", 9.25)
                        num = ['5A';'A5';'04';'00';'01';'05';'AA'];  %左
                        num = hex2dec(num);
                        write(device,num,'string')
                        pause(1)
                        num = ['5A';'A5';'04';'00';'00';'04';'AA'];  %停
                        num = hex2dec(num);
                        write(device,num,'string')
                end

                %%%%%%%%%%%%%%% 小车运动 %%%%%%%%%%%%%%%
                % 控制协议：%
                % 前：  ['5A';'A5';'04';'01';'00';'05';'AA']
                % 左前：['5A';'A5';'04';'01';'01';'06';'AA']
                % 左：  ['5A';'A5';'04';'00';'01';'05';'AA']
                % 左后：['5A';'A5';'04';'02';'01';'07';'AA']
                % 后：  ['5A';'A5';'04';'02';'00';'06';'AA']
                % 后右：['5A';'A5';'04';'02';'02';'08';'AA']
                % 右：  ['5A';'A5';'04';'00';'02';'06';'AA']
                % 右前：['5A';'A5';'04';'01';'02';'07';'AA']
                % 停：  ['5A';'A5';'04';'00';'00';'04';'AA']
                %%%%%%%%%%%%%%% END %%%%%%%%%%%%%%%


            end
            app.Lamp.Color = [0.00,1.00,0.00];


            %%%%%%%%%%%%%%% CCA %%%%%%%%%%%%%%%
            function max_id = find_SSVEP_frequency(data)
                % 获取刺激频率的数量
                t = (0:1/250:(size(data, 2)-1)/250)';
                rous = [];

                EEG_data = data;

                for fr = [6.6, 7.5, 8.57, 10]
                    reference_signals = [];
                    for n = 1:2
                        reference_signals = [reference_signals;sin(2*pi*fr*n*t)';cos(2*pi*fr*n*t)'];
                    end
                    % 执行CCA分析
                    [~, ~, cca_r] = canoncorr(EEG_data', reference_signals');
                    rous = [rous, cca_r(1)];
                end
                [~, max_id] = max(rous);
            end
            %%%%%%%%%%%%%%% 更新表格 %%%%%%%%%%%%%%%
            function updateTable(app, trial, num, direction, frequcecy)
                % 创建新数据条目作为一行
                newRow = {int64(trial), num, direction, frequcecy};
                % 将新行添加到现有数据中
                currentData = get(app.UITable, 'Data');
                newData = [currentData; newRow];
                set(app.UITable, 'Data', newData);
            end
            %%%%%%%%%%%%%%% END %%%%%%%%%%%%%%%
        end

        % Value changed function: SamplingTimeEditField
        function SamplingTimeEditFieldValueChanged(app, event)

        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)

        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.board_shim.release_session();
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.board_shim.release_session();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1000];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'FFT')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 50];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'START';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '采样时长/s';
            app.SamplingTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingTimeEditFieldValueChanged, true);
            app.SamplingTimeEditField.Value = 1;
            app.COMEditFieldLabel = uilabel(app.UIFigure);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UIFigure, 'numeric');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = 11;
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '当前预测值';
            app.predict_EditField = uieditfield(app.UIFigure, 'numeric');
            app.flEditFieldLabel = uilabel(app.UIFigure);
            app.flEditFieldLabel.Text = 'fl';
            app.frequencylow = uieditfield(app.UIFigure, 'numeric');
            app.frequencylow.Value = 5;
            app.fhLabel = uilabel(app.UIFigure);
            app.fhLabel.Text = 'fh';
            app.frequencyhigh = uieditfield(app.UIFigure, 'numeric');
            app.frequencyhigh.Value = 16;
            app.channelchoosePanel = uipanel(app.UIFigure);
            app.channelchoosePanel.Title = 'channel choose';
            app.Channel1CheckBox = uicheckbox(app.channelchoosePanel);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel2CheckBox = uicheckbox(app.channelchoosePanel);
            app.Channel2CheckBox.Text = 'Channel 2 ';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBox = uicheckbox(app.channelchoosePanel);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel4CheckBox = uicheckbox(app.channelchoosePanel);
            app.Channel4CheckBox.Text = 'Channel 4';
            app.Channel5CheckBox = uicheckbox(app.channelchoosePanel);
            app.Channel5CheckBox.Text = 'Channel 5';
            app.Channel6CheckBox = uicheckbox(app.channelchoosePanel);
            app.Channel6CheckBox.Text = 'Channel 6';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '滤波频率选择';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '系统指示灯';
            app.Lamp = uilamp(app.Panel);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'trials'; '当前预测值'; '运动方向'; '刺激频率'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appbci2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end